classdef OperacionesBasicas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterpolarCeroButton            matlab.ui.control.Button
        InterpolarEscalonButton         matlab.ui.control.Button
        InterCerohButton_h              matlab.ui.control.Button
        InterEschButton_h               matlab.ui.control.Button
        InterCeroxButton_x              matlab.ui.control.Button
        InterEscxButton_x               matlab.ui.control.Button
        InterpolacinCero_F              matlab.ui.control.Button
        TipodeInterpolacinLabel_3       matlab.ui.control.Label
        InterpolacinEscalon_F           matlab.ui.control.Button
        InterpolacinLineal_F            matlab.ui.control.Button
        TipodeInterpolacinLabel_2       matlab.ui.control.Label
        InterpolacinCero_h              matlab.ui.control.Button
        InterpolacinEscalon_h           matlab.ui.control.Button
        InterpolacinLineal_h            matlab.ui.control.Button
        TipodeInterpolacinLabel         matlab.ui.control.Label
        InterpolacinCero_x              matlab.ui.control.Button
        InterpolacinEscalon_x           matlab.ui.control.Button
        InterpolacinLineal_x            matlab.ui.control.Button
        OrigenFnLabel_value             matlab.ui.control.Label
        OrigenhnLabel_value             matlab.ui.control.Label
        OrigenxnLabel_value             matlab.ui.control.Label
        disFnEditField                  matlab.ui.control.EditField
        SealFnLabel                     matlab.ui.control.Label
        dishnEditField                  matlab.ui.control.EditField
        SealhnLabel                     matlab.ui.control.Label
        disxnEditField                  matlab.ui.control.EditField
        SealxnLabel                     matlab.ui.control.Label
        OrigenLabel_3                   matlab.ui.control.Label
        OrigenLabel                     matlab.ui.control.Label
        OrigenLabel_2                   matlab.ui.control.Label
        InterxButton_x                  matlab.ui.control.Button
        DiezxButton_x                   matlab.ui.control.Button
        DiezhButton_h                   matlab.ui.control.Button
        InterhButton_h                  matlab.ui.control.Button
        DiezmacinButton_x               matlab.ui.control.Button
        InterpolacinButton_x            matlab.ui.control.Button
        DiezmacinButton_h               matlab.ui.control.Button
        InterpolacinButton_h            matlab.ui.control.Button
        ReflejoButton_x                 matlab.ui.control.Button
        ReflejoButton_h                 matlab.ui.control.Button
        DiezmarButton                   matlab.ui.control.Button
        InterpolarButton                matlab.ui.control.Button
        FactorDesphButton               matlab.ui.control.Button
        DesplazamientoButton_h          matlab.ui.control.Button
        DespFButton                     matlab.ui.control.Button
        FactorDespxButton               matlab.ui.control.Button
        DesplazamientoButton_x          matlab.ui.control.Button
        InstruccionesLabel_4            matlab.ui.control.Label
        InstruccionesLabel_3            matlab.ui.control.Label
        InstruccionesLabel_2            matlab.ui.control.Label
        InstruccionesLabel_1            matlab.ui.control.Label
        Practica2OperacionesbasicasLabel  matlab.ui.control.Label
        RestaButton                     matlab.ui.control.Button
        SumaButton                      matlab.ui.control.Button
        AtnFButton                      matlab.ui.control.Button
        CancelarButton_h                matlab.ui.control.Button
        CancelarButton_x                matlab.ui.control.Button
        CancelarButton                  matlab.ui.control.Button
        AmpFButton                      matlab.ui.control.Button
        FactorkEditField                matlab.ui.control.NumericEditField
        FactorkLabel                    matlab.ui.control.Label
        FactorAtnhButton                matlab.ui.control.Button
        FactorAtnxButton                matlab.ui.control.Button
        FactorAmpxEditField             matlab.ui.control.NumericEditField
        FactordeamplificacinkEditFieldLabel_3  matlab.ui.control.Label
        FactorAmphEditField             matlab.ui.control.NumericEditField
        FactordeamplificacinkEditFieldLabel_2  matlab.ui.control.Label
        FactorAmphButton                matlab.ui.control.Button
        FactorAmpxButton                matlab.ui.control.Button
        SealresultadoLabel              matlab.ui.control.Label
        AtenuacinButton_h               matlab.ui.control.Button
        AmplificacinButton_h            matlab.ui.control.Button
        AtenuacinButton_x               matlab.ui.control.Button
        AmplificacinButton_x            matlab.ui.control.Button
        inicialEditField_hn             matlab.ui.control.NumericEditField
        PosicininicialEditField_2Label  matlab.ui.control.Label
        inicialEditField_xn             matlab.ui.control.NumericEditField
        PosicininicialEditFieldLabel_2  matlab.ui.control.Label
        GraficarhnButton                matlab.ui.control.Button
        GraficarxnButton                matlab.ui.control.Button
        DesplazamientoButton_F          matlab.ui.control.Button
        ReflejoButton                   matlab.ui.control.Button
        AtenuacinButton_F               matlab.ui.control.Button
        AmplificacinButton_F            matlab.ui.control.Button
        DiezmacinButton                 matlab.ui.control.Button
        InterpolacinButton              matlab.ui.control.Button
        ConvolucinButton                matlab.ui.control.Button
        SumaRestaButton                 matlab.ui.control.Button
        IntroduzcasussealesLabel        matlab.ui.control.Label
        xnEditField                     matlab.ui.control.EditField
        xnEditFieldLabel                matlab.ui.control.Label
        hnEditField                     matlab.ui.control.EditField
        hnEditFieldLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_h                        matlab.ui.control.UIAxes
        UIAxes_x                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xn % Señal x(n)
        inicio_x % Desplazamioento para el punto inicial de x(n)
        origen_x % Origen para desplazamientos de x(n)
        
        hn % Señal h(n)
        inicio_h % Desplazamioento para el punto inicial de h(n)
        origen_h % Origen para desplazamientos de h(n)
        
        Fn % Señal F(n)
        inicio_F % Desplazamioento para el punto inicial de F(n)
        origen_F % Origen para desplazamientos de F(n)
    end
    
    methods (Access = private)
        
        function visualizarOpc(app)
            app.DiezmacinButton.Visible = 'on';
            app.InterpolacinButton.Visible = 'on';
            app.AmplificacinButton_F.Visible = 'on';
            app.AtenuacinButton_F.Visible = 'on';
            app.ReflejoButton.Visible = 'on';
            app.DesplazamientoButton_F.Visible = 'on';
        end
        
        function plotXn(app)
            cla(app.UIAxes_x, 'reset');
            xline(app.UIAxes_x, 0);
            stem(app.UIAxes_x, app.inicio_x, app.xn,'filled','markersize', 4, "Color", [0.6350, 0.0780, 0.1840]); 
            grid(app.UIAxes_x, 'on');
            title(app.UIAxes_x,'Señal x(n)');
            xlabel(app.UIAxes_x,'n'); 
            ylabel(app.UIAxes_x,'x(n)'); 
        end


        function plotHn(app)
            cla(app.UIAxes_h, 'reset');
            xline(app.UIAxes_h, 0);
            stem(app.UIAxes_h, app.inicio_h, app.hn, 'filled','markersize', 4, "Color", [0, 0.4470, 0.7410]); 
            grid(app.UIAxes_h, 'on');
            title(app.UIAxes_h,'Señal h(n)');
            xlabel(app.UIAxes_h,'n'); 
            ylabel(app.UIAxes_h,'h(n)'); 
        end
        
        function plotFn(app)
            cla(app.UIAxes, 'reset');
            xline(app.UIAxes, 0);
            stem(app.UIAxes, app.inicio_F, app.Fn, 'filled','markersize', 4, "Color",[0.4660, 0.6740, 0.1880]); 
            grid(app.UIAxes, 'on');
            title(app.UIAxes,'Señal F(n)');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
            
        end
        
        function valoresDefinidos(app)
            if length(app.xn) > 0 && length(app.hn) > 0
                app.Practica2OperacionesbasicasLabel.Visible = 'off';
                app.InstruccionesLabel_1.Visible = 'off';
                app.InstruccionesLabel_2.Visible = 'off';
                app.InstruccionesLabel_3.Visible = 'off';
                app.InstruccionesLabel_4.Visible = 'off';
                app.UIAxes.Visible = 'on';
                app.SealresultadoLabel.Visible = 'on';
                app.ConvolucinButton.Visible = 'on';
                app.SumaRestaButton.Visible = 'on';
            end
        end
        
        function displayValuesXn(app)
            app.SealxnLabel.Visible = 'on';
            app.OrigenLabel.Visible = 'on';
            app.OrigenxnLabel_value.Visible = 'on';
            app.disxnEditField.Visible = 'on';
            origen = app.xn((-1*min(app.inicio_x))+1);
            app.OrigenxnLabel_value.Text = "x(" + mat2str(-1*min(app.inicio_x)) + ") = " + mat2str(origen);
            app.disxnEditField.Value = mat2str(app.xn);
        end
        
        function displayDespValuesXn(app)
            app.SealxnLabel.Visible = 'on';
            app.OrigenLabel.Visible = 'on';
            app.OrigenxnLabel_value.Visible = 'on';
            app.disxnEditField.Visible = 'on';
            indice = length(app.xn) - (length(app.xn) - app.origen_x);
            origen = app.xn(indice+1);
            app.OrigenxnLabel_value.Text = "x(" + mat2str(app.origen_x) + ") = " + mat2str(origen);
            app.disxnEditField.Value = mat2str(app.xn);
        end
        
        function displayValuesHn(app)
            app.SealhnLabel.Visible = 'on';
            app.OrigenLabel_3.Visible = 'on';
            app.OrigenhnLabel_value.Visible = 'on';
            app.dishnEditField.Visible = 'on';
            origen = app.hn((-1*min(app.inicio_h))+1);
            app.OrigenhnLabel_value.Text = "h(" + mat2str(-1*min(app.inicio_h)) + ") = " + mat2str(origen);
            app.dishnEditField.Value = mat2str(app.hn);
        end
        
        function displayDespValuesHn(app)
            app.SealhnLabel.Visible = 'on';
            app.OrigenLabel_3.Visible = 'on';
            app.OrigenhnLabel_value.Visible = 'on';
            app.dishnEditField.Visible = 'on';
            indice = length(app.hn) - (length(app.hn) - app.origen_h);
            origen = app.hn(indice+1);
            app.OrigenhnLabel_value.Text = "x(" + mat2str(app.origen_h) + ") = " + mat2str(origen);
            app.dishnEditField.Value = mat2str(app.hn);
        end
        
        function displayValuesFn(app)
            app.SealFnLabel.Visible = 'on';
            app.OrigenLabel_2.Visible = 'on';
            app.OrigenFnLabel_value.Visible = 'on';
            app.disFnEditField.Visible = 'on';
            origen = app.Fn((-1*min(app.inicio_F))+1);
            app.OrigenFnLabel_value.Text = "h(" + mat2str(-1*min(app.inicio_F)) + ") = " + mat2str(origen);
            app.disFnEditField.Value = mat2str(app.Fn);
        end
        
        function displayDespValuesFn(app)
            app.SealFnLabel.Visible = 'on';
            app.OrigenLabel_2.Visible = 'on';
            app.OrigenFnLabel_value.Visible = 'on';
            app.disFnEditField.Visible = 'on';
            indice = length(app.Fn) - (length(app.Fn) - app.origen_F);
            origen = app.Fn(indice+1);
            app.OrigenFnLabel_value.Text = "x(" + mat2str(app.origen_F) + ") = " + mat2str(origen);
            app.disFnEditField.Value = mat2str(app.Fn);
        end
        
        function displayReflexValuesXn(app)
              
            app.SealxnLabel.Visible = 'on';
            app.OrigenLabel.Visible = 'on';
            app.OrigenxnLabel_value.Visible = 'on';
            app.disxnEditField.Visible = 'on';
            reflejo = app.xn;
            origen = app.xn(length(app.xn)-(-1*min(app.inicio_x)));
            app.OrigenxnLabel_value.Text = "x(" + mat2str(-1*min(app.inicio_x)) + ") = " + mat2str(origen);
            app.disxnEditField.Value = mat2str(fliplr(reflejo));
        end
        
        function displayReflexValuesHn(app)
              
            app.SealhnLabel.Visible = 'on';
            app.OrigenLabel_3.Visible = 'on';
            app.OrigenhnLabel_value.Visible = 'on';
            app.dishnEditField.Visible = 'on';
            reflejo = app.hn;
            origen = app.hn(length(app.hn)-(-1*min(app.inicio_h)));
            app.OrigenhnLabel_value.Text = "x(" + mat2str(-1*min(app.inicio_h)) + ") = " + mat2str(origen);
            app.dishnEditField.Value = mat2str(fliplr(reflejo));
        end
        
        function displayReflexValuesFn(app)
              
            app.SealFnLabel.Visible = 'on';
            app.OrigenLabel.Visible = 'on';
            app.OrigenFnLabel_value.Visible = 'on';
            app.disFnEditField.Visible = 'on';
            reflejo = app.Fn;
            origen = app.Fn(length(app.Fn)-(-1*min(app.inicio_F)));
            app.OrigenFnLabel_value.Text = "x(" + mat2str(-1*min(app.inicio_F)) + ") = " + mat2str(origen);
            app.disFnEditField.Value = mat2str(fliplr(reflejo));
        end
  
        function [resultadoS,niS] = Convolucion(app, x, y, nix, niy)
            
            if length(x) > length(y)
                y(numel(x)) = 0;
            elseif length(y) > length(x)
                x(numel(y)) = 0;
            end
            
            if length(nix) > length(niy)
                niy(numel(nix)) = 0;
            elseif length(niy) > length(nix)
                nix(numel(niy)) = 0;
            end
            
            % Calculamos el tamaño de la matriz
            N = length(x) + length(y) - 1;
            diferencia = N - length(x);
        
            xVolteado = fliplr(x);
        
            % Llenamos la primer fila de la matriz
            fila = [];
            Cx = [];
        
            % Colocamos el primer valor
            fila = [x(1)];
            % Colocamos los 0's correspondientes para el llenado de la matriz
            for i=1:diferencia
                fila = [fila, 0]; 
            end
            % Colocamos los valores restantes de nuestra primera función
            for i=1:(length(x)-1)
                fila = [fila, xVolteado(i)];
            end
            Cx = [fila];
            columnaCopia = fila;
            fila = [];
        
            % Llenamos el resto de filas de la matriz con base a la primer fila
            for i=1:(N-1)
                fila = [columnaCopia(N)];
                for j=1:(N-1)
                    fila = [fila, columnaCopia(j)];
                end
                Cx = [Cx; fila];
                columnaCopia = fila;
                fila = [];
            end
        
            % Rellenamos de 0's nuestra segunda función para que tenga el tamaño de
            % la matriz
            yc = y;
            for i=1:(N-length(yc))
                yc = [yc, 0];
            end
        
            % Realizamos la multiplicación de matrices
            suma = [];
            valor = 0;
            resultado = [];
            for i=1:N
                fila = Cx(i,:);
                for j=1:N
                    valor = valor + (fila(j)*yc(j));
                end
                resultado = [resultado, valor];
                valor = 0;
            end
        
            ni = -nix-niy;
            ni = -ni;
            
            ni = ni:length(resultado)-(-ni + 1);
            
            
            resultadoS = resultado;
            niS = ni;
        end
        
        
        function [resultadoS, niS] = Suma(app, x,y,nix, niy)
             %tamaño del vector X y del vector Y
            tam_x = length(x);
            tam_y = length(y);
            vec = [];
            
            if(nix == niy) %cuando ambas posiciones iniciales son iguales
                diferencia = abs(tam_x-tam_y);
                if(tam_x > tam_y)
                    for i=1:diferencia
                        y = [y 0];
                    end
                elseif(tam_y > tam_x) 
                    for i=1:diferencia
                        x = [x 0];
                    end
                end
                vec = x + y;
                ni_vec = nix;
            else    %cuando las posiciones iniciales de los vectores son diferetentes
                dif_ladox = tam_x - nix + 1;
                dif_ladoy = tam_y - niy + 1;
                ceros_derecha = abs(dif_ladox - dif_ladoy);
                ceros_izquierda = abs(nix - niy);
                if(nix < niy)   %si la posición inicial del vector x es menor que la de y
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        x = [0 x];  %agrega los ceros correspondientes a la izquierda de x
                    end
                    vec = x + y;     %ahora que los vectores son iguales, se suman
                    ni_vec = niy;
                elseif(niy < nix)
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        y = [0 y];  %agrega los ceros correspondientes a la izquierda de y
                    end
                    vec = x + y;     %ahora que los vectores son iguales, se suman
                    ni_vec = nix;
                end
            end
                
            
            ni = ni_vec;
            ni = -ni;
            ni = ni:length(vec)-ni_vec-1;
            
            resultadoS = vec;
            niS = ni;
            x
            y
            vec
            ni
        end
        
        function [resultadoS, niS] = Resta(app,x,y,nix, niy)
            %tamaño del vector X y del vector Y
            tam_x = length(x);
            tam_y = length(y);
            vec = [];
            
            if(nix == niy) %cuando ambas posiciones iniciales son iguales
                diferencia = abs(tam_x-tam_y);
                if(tam_x > tam_y)
                    for i=1:diferencia
                        y = [y 0];
                    end
                elseif(tam_y > tam_x) 
                    for i=1:diferencia
                        x = [x 0];
                    end
                end
                vec = x - y;
                ni_vec = nix;
            else    %cuando las posiciones iniciales de los vectores son diferetentes
                dif_ladox = tam_x - nix + 1;
                dif_ladoy = tam_y - niy + 1;
                ceros_derecha = abs(dif_ladox - dif_ladoy);
                ceros_izquierda = abs(nix - niy);
                if(nix < niy)   %si la posición inicial del vector x es menor que la de y
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        x = [0 x];  %agrega los ceros correspondientes a la izquierda de x
                    end
                    vec = x - y;     %ahora que los vectores son iguales, se suman
                    ni_vec = niy;
                elseif(niy < nix)
                    if(dif_ladox < dif_ladoy)
                        for i=1:ceros_derecha
                            x = [x 0];  %agrega los ceros correspondientes a la derecha de x
                        end
                    elseif(dif_ladoy < dif_ladox)
                        for i=1:ceros_derecha
                            y = [y 0];  %agrega los ceros correspondientes a la derecha de y
                        end
                    end
                    for i=1:ceros_izquierda
                        y = [0 y];  %agrega los ceros correspondientes a la izquierda de y
                    end
                    vec = x - y;     %ahora que los vectores son iguales, se restan los vectores
                    ni_vec = nix;
                end
            end
                
            
            ni = ni_vec;
            ni = -ni;
            ni = ni:length(vec)-ni_vec-1;
            
            resultadoS = vec;
            niS = ni;
            x
            y
            vec
            ni
        end
        
        function [resultadoS,niS] = Reflejo(app, x, nix)     
            fliplr(x)
            for i=1:length(nix)
                nix(i) = -1*nix(i);
            end
            resultadoS = x;
            niS = nix;
        end
        
        function [respuesta] = graficacion(app, x, ni)
            y = [];
            for i=0:ni
                y = [y, -i];
            end
            y = fliplr(y);
            N = length(x);
            for i=1:(N-ni)
                y  = [y, i];
            end
            respuesta = y;
        end
        
        function [resultado,ni] = Interpolacion(app, x,valorInterpolacion,nix)
            %%resultado = interp(x, valorInterpolacion);
            %%ni = min(nix):length(x) - (min(nix) + 1); 
            y = [];
            
            N = length(x);
            xi = [x,0];
            for i=1:N
                valorIngremental = (xi(i+1)-xi(i))/valorInterpolacion;
                y = [y, xi(i)];
                guardado = xi(i)
                for j=1:(valorInterpolacion-1)
                    guardado = guardado + valorIngremental;
                    y = [y, guardado];
                end
            end 
            
            resultado = y;
            %[ni] = graficacion(app, resultado, nix);
            ni = min(nix):length(resultado) - ((-1*min(nix)) + 1); 
            disp(length(resultado));
            disp(length(ni));
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function [resultado,ni] = InterpolacionCero(app, x,valorInterpolacion,nix)
           y = [];
    
           N = length(x);
           xi = [x,0];
            for i=1:N
                y = [y, xi(i)];
                for j=1:(valorInterpolacion-1)
                    y = [y, 0];
                end
            end
            resultado = y;
            ni = min(nix):length(resultado) - ((-1*min(nix)) + 1); 
        end
       
        function [resultado,ni] = InterpolacionEscalon(app, x,valorInterpolacion,nix)
            y = [];
            N = length(x);
            xi = [x,0];
            for i=1:N
                y = [y, xi(i)];
                for j=1:(valorInterpolacion-1)
                    y = [y, xi(i)];
                end
            end
            resultado = y;
            ni = min(nix):length(resultado) - ((-1*min(nix)) + 1); 
            %ni = nix*valorInterpolacion;
            %ni = min(ni):length(resultado) - (min(ni) + 1); 
        end
       
        function [Resultado,ni] = diezmacion(app,x,valorDiezmacion,nix)
            y= [];
            n = [];
            disp(length(x)/valorDiezmacion);
            for i = 0 : (length(x)/valorDiezmacion - 1)
                disp(i+valorDiezmacion);
                y = [y,x( ( (i+1)*valorDiezmacion )) ];
                n = [n,nix(((i+1)*+valorDiezmacion)) ];
                
            end
            ni = [];
            contador = min(n);
            while length(ni) < length(n) 
                ni = [ni, contador];
                contador = contador + 1;
            end
            
            Resultado = y;
        end
        
        function [Resultado,ni] = Diezmacion2(app,x,valorDiezmacion,nix)
            y= [];
            n = [];
            %disp(length(x)/valorDiezmacion);
            inicio = (-1 * min(nix)) + 1;
            i = inicio;
            while i <= length(x) 
                y = [y,x(i) ];
                n = [n,nix(i)];
                
                disp("INDICE 1: ");
                disp(i);
                
                i = i+valorDiezmacion;
            end
            
            i = inicio - valorDiezmacion;
            contador = 0;
            
            while i >= 0
                
                if i > 0
                    y = [x(i), y];
                    n = [nix(i), n];
                    
                    disp("INDICE 2: ");
                    disp(i);
                    
                    contador = contador + 1;
                end
                
                i = i - valorDiezmacion;
                
            end
            
            inicial = contador;
            
            Resultado = y;
            ni = (-1 * inicial):length(Resultado)-(inicial + 1);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarxnButton
        function GraficarxnButtonPushed(app, event)
             cla(app.UIAxes_x, 'reset');
             vec = str2double(strsplit(app.xnEditField.Value)); 
             inicial = app.inicialEditField_xn.Value;
             app.origen_x = inicial;
             if (inicial < length(vec) && inicial >= 0)
                xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             app.xn = vec;
             app.inicio_x = xi;
             plotXn(app);
             
             app.AmplificacinButton_x.Visible = 'on';
             app.AtenuacinButton_x.Visible = 'on';
             app.DesplazamientoButton_x.Visible = 'on';
             app.ReflejoButton_x.Visible = 'on';
             app.DiezmacinButton_x.Visible = 'on';
             app.InterpolacinButton_x.Visible = 'on';
             valoresDefinidos(app);
             displayValuesXn(app);
             
        end

        % Button pushed function: GraficarhnButton
        function GraficarhnButtonPushed(app, event)
             cla(app.UIAxes_h, 'reset');
             vec = str2double(strsplit(app.hnEditField.Value)); 
             inicial = app.inicialEditField_hn.Value;
             app.origen_h = inicial;
             if (inicial < length(vec) && inicial >= 0)
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             app.hn = vec;
             app.inicio_h = xi;
             plotHn(app);
             
             app.AmplificacinButton_h.Visible = 'on';
             app.AtenuacinButton_h.Visible = 'on';
             app.DesplazamientoButton_h.Visible = 'on';
             app.ReflejoButton_h.Visible = 'on';
             app.DiezmacinButton_h.Visible = 'on';
             app.InterpolacinButton_h.Visible = 'on';
             
             valoresDefinidos(app);
             displayValuesHn(app);
             
        end

        % Button pushed function: AmplificacinButton_x
        function AmplificacinButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.InterCeroxButton_x.Visible = 'off';
            app.InterEscxButton_x.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor de amplificación (k) =';
            app.FactorAmpxButton.Visible = 'on';
            app.FactorAtnxButton.Visible = 'off';
            app.CancelarButton_x.Visible = 'on';
            app.FactorDespxButton.Visible = 'off';
            app.InterxButton_x.Visible = 'off';
            app.DiezxButton_x.Visible = 'off';
            app.InterpolacinLineal_x.Visible='off';
            app.InterpolacinEscalon_x.Visible='off';
            app.InterpolacinCero_x.Visible='off';
            app.TipodeInterpolacinLabel.Visible='off';
        end

        % Button pushed function: FactorAmpxButton
        function FactorAmpxButtonPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            app.xn = app.xn * k;
            plotXn(app);
            displayValuesXn(app);
        end

        % Button pushed function: FactorAtnxButton
        function FactorAtnxButtonPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            app.xn = app.xn / k;
            plotXn(app);
            displayValuesXn(app);
        end

        % Button pushed function: AtenuacinButton_x
        function AtenuacinButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.InterCeroxButton_x.Visible = 'off';
            app.InterEscxButton_x.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor de atenuación (k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'on';
            app.CancelarButton_x.Visible = 'on';
            app.FactorDespxButton.Visible = 'off';
            app.InterxButton_x.Visible = 'off';
            app.DiezxButton_x.Visible = 'off';
            app.InterpolacinLineal_x.Visible='off';
            app.InterpolacinEscalon_x.Visible='off';
            app.InterpolacinCero_x.Visible='off';
            app.TipodeInterpolacinLabel.Visible='off';
        end

        % Button pushed function: AmplificacinButton_h
        function AmplificacinButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.InterhButton_h.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor de amplificación (k) =';
            app.FactorAmphButton.Visible = 'on';
            app.FactorAtnhButton.Visible = 'off';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.DiezhButton_h.Visible = 'off';
        end

        % Button pushed function: AtenuacinButton_h
        function AtenuacinButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.InterhButton_h.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor de atenuación (k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'on';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.DiezhButton_h.Visible = 'off';
        end

        % Button pushed function: FactorAmphButton
        function FactorAmphButtonPushed(app, event)
            k = app.FactorAmphEditField.Value;
            app.hn = app.hn * k;
            plotHn(app);
            displayValuesHn(app);
        end

        % Button pushed function: FactorAtnhButton
        function FactorAtnhButtonPushed(app, event)
            k = app.FactorAmphEditField.Value;
            app.hn = app.hn / k;
            plotHn(app);
            displayValuesHn(app);
        end

        % Button pushed function: CancelarButton_x
        function CancelarButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'off';
            app.FactorDespxButton.Visible = 'off';
            app.CancelarButton_x.Visible = 'off';
            app.InterxButton_x.Visible = 'off';
            app.DiezxButton_x.Visible = 'off';
            app.InterpolacinLineal_x.Visible='off';
            app.InterpolacinEscalon_x.Visible='off';
            app.InterpolacinCero_x.Visible='off';
            app.TipodeInterpolacinLabel.Visible='off';
            app.InterxButton_x.Visible = 'off';
            app.InterCeroxButton_x.Visible = 'off';
            app.InterEscxButton_x.Visible = 'off';
            
        end

        % Button pushed function: CancelarButton_h
        function CancelarButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'off';
            app.InterhButton_h.Visible = 'off';
            app.InterEschButton_h.Visible = 'off';
            app.InterCerohButton_h.Visible = 'off';
            app.DiezhButton_h.Visible = 'off';
            app.InterpolacinLineal_h.Visible='off';
            app.InterpolacinEscalon_h.Visible='off';
            app.InterpolacinCero_h.Visible='off';
            app.TipodeInterpolacinLabel_2.Visible='off';
        end

        % Button pushed function: AmplificacinButton_F
        function AmplificacinButton_FPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'on';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.InterpolarButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'off';
            app.FactorkLabel.Visible = 'off';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.InterpolarButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.CancelarButton.Visible = 'off';
            app.InterpolacinCero_F.Visible='off';
            app.InterpolacinLineal_F.Visible='off';
            app.InterpolacinEscalon_F.Visible='off';
            app.TipodeInterpolacinLabel_3.Visible='off';
            app.InterpolarCeroButton.Visible = 'off';
            app.InterpolarEscalonButton.Visible = 'off';
               
        end

        % Button pushed function: AtenuacinButton_F
        function AtenuacinButton_FPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'on';
            app.DespFButton.Visible = 'off';
            app.InterpolarButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
           
        end

        % Button pushed function: ConvolucinButton
        function ConvolucinButtonPushed(app, event)
            visualizarOpc(app);
            [app.Fn, app.inicio_F] = Convolucion(app,app.xn, app.hn, app.inicio_x, app.inicio_h);
            app.origen_x = find(app.inicio_F == 0);
            app.origen_F = app.origen_F;
            %disp(app.Fn);
            %disp(app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: AtnFButton
        function AtnFButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            app.Fn = app.Fn / k;
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: AmpFButton
        function AmpFButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            app.Fn = app.Fn * k;
            plotFn(app); 
            displayValuesFn(app);
        end

        % Button pushed function: SumaRestaButton
        function SumaRestaButtonPushed(app, event)
            app.RestaButton.Visible = 'on';
            app.SumaButton.Visible = 'on';
            app.FactorkEditField.Visible = 'off';
            app.FactorkLabel.Visible = 'off';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.InterpolarButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
        end

        % Button pushed function: DesplazamientoButton_x
        function DesplazamientoButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.InterCeroxButton_x.Visible = 'off';
            app.InterEscxButton_x.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor desplazamiento(k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'off';
            app.FactorDespxButton.Visible = 'on';
            app.CancelarButton_x.Visible = 'on';
            app.InterxButton_x.Visible = 'off';
            app.DiezxButton_x.Visible = 'off';
            app.InterpolacinLineal_x.Visible='off';
            app.InterpolacinEscalon_x.Visible='off';
            app.InterpolacinCero_x.Visible='off';
            app.TipodeInterpolacinLabel.Visible='off';
        end

        % Button pushed function: FactorDespxButton
        function FactorDespxButtonPushed(app, event)
             k = app.FactorAmpxEditField.Value;
             %Desplazar hacia arriba o abajo
%            if k>0
%                 app.xn = app.xn + abs(k);
%                 plotXn(app); 
%            else     
%                 app.xn = app.xn - abs(k);
%                 plotXn(app); 
%            end

            %%DESPLAZAR HACIA LA DERECHA O IZQUIERDA%%
            if k>0
                app.inicio_x = app.inicio_x - abs(k);
                app.origen_x = app.origen_x - abs(k);
                plotXn(app); 
                displayDespValuesXn(app);
             else     
                app.inicio_x = app.inicio_x + abs(k);
                app.origen_x = app.origen_x + abs(k);
                plotXn(app); 
                displayValuesXn(app);
             end
           
        end

        % Button pushed function: DesplazamientoButton_F
        function DesplazamientoButton_FPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'on';
            app.InterpolarButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.CancelarButton.Visible = 'on';
            app.InterpolarEscalonButton.Visible = 'off';
           app.InterpolarCeroButton.Visible = 'off';
        end

        % Button pushed function: DespFButton
        function DespFButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            %%DESPLAZAR HACIA LA ARRIBA O ABAJO%%
%             if k>0
%                 app.Fn = app.Fn + abs(k);
%                 plotFn(app);
%             else
%                 app.Fn = app.Fn - abs(k);
%                 plotFn(app);
%             end

            %%DESPLAZAR HACIA LA DERECHA O IZQUIERDA%%
            if k>0
                app.inicio_F = app.inicio_F - abs(k);
                plotFn(app); 
                app.origen_F = app.origen_F - abs(k);
                displayDespValuesFn(app);
             else     
                app.inicio_F = app.inicio_F + abs(k);
                plotFn(app); 
                app.origen_F = app.origen_F + abs(k);
                displayValuesXn(app);
                
            end
            
        end

        % Button pushed function: SumaButton
        function SumaButtonPushed(app, event)
            visualizarOpc(app);
            [app.Fn, app.inicio_F] = Suma(app,app.xn, app.hn, app.inicialEditField_xn.Value, app.inicialEditField_hn.Value);
            app.origen_x = find(app.inicio_F == 0);
            app.origen_F = app.origen_F;
            %disp(app.Fn);
            %disp(app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: ReflejoButton
        function ReflejoButtonPushed(app, event)
            [app.Fn, app.inicio_F] = Reflejo(app, app.Fn, app.inicio_F);
            disp(app.Fn);
            disp(app.inicio_F);
            plotFn(app);
            displayReflexValuesFn(app);
        end

        % Button pushed function: RestaButton
        function RestaButtonPushed(app, event)
            visualizarOpc(app);
            [app.Fn, app.inicio_F] = Resta(app,app.xn, app.hn, app.inicialEditField_xn.Value, app.inicialEditField_hn.Value);
            app.origen_x = find(app.inicio_F == 0);
            app.origen_F = app.origen_F;
            %disp(app.Fn);
            %disp(app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: DesplazamientoButton_h
        function DesplazamientoButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.InterhButton_h.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor desplazamiento(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.FactorDesphButton.Visible = 'on';
            app.DiezhButton_h.Visible = 'off';
        end

        % Button pushed function: FactorDesphButton
        function FactorDesphButtonPushed(app, event)
             k = app.FactorAmphEditField.Value;
             %Desplazar hacia arriba o abajo
%            if k>0
%                 app.xn = app.xn + abs(k);
%                 plotXn(app); 
%            else     
%                 app.xn = app.xn - abs(k);
%                 plotXn(app); 
%            end

            %%DESPLAZAR HACIA LA DERECHA O IZQUIERDA%%
            if k>0
                app.inicio_h = app.inicio_h - abs(k);
                plotHn(app); 
                app.origen_h = app.origen_h - abs(k);
                displayDespValuesHn(app);
             else     
                app.inicio_h = app.inicio_h + abs(k);
                plotHn(app); 
                app.origen_h = app.origen_h + abs(k);
                displayValuesHn(app);
            end
            
        end

        % Button pushed function: InterpolacinButton
        function InterpolacinButtonPushed(app, event)
           app.RestaButton.Visible = 'off';
           app.SumaButton.Visible = 'off';
           app.FactorkEditField.Visible = 'off';
           app.FactorkLabel.Visible = 'off';
           app.AmpFButton.Visible = 'off';
           app.AtnFButton.Visible = 'off';
           app.DespFButton.Visible = 'off';
           app.DiezmarButton.Visible = 'off';
           app.InterpolarButton.Visible = 'off';
           app.CancelarButton.Visible = 'on';
           app.InterpolacinCero_F.Visible='on';
           app.InterpolacinLineal_F.Visible='on';
           app.InterpolacinEscalon_F.Visible='on';
           app.TipodeInterpolacinLabel_3.Visible='on';
           app.FactorkEditField.Visible = 'off';
           app.FactorkLabel.Visible = 'off';
           app.InterpolarEscalonButton.Visible = 'off';
           app.InterpolarCeroButton.Visible = 'off';
        end

        % Button pushed function: DiezmacinButton
        function DiezmacinButtonPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.InterpolarButton.Visible = 'off';
            app.DiezmarButton.Visible = 'on';
            app.CancelarButton.Visible = 'on';
            app.InterpolarEscalonButton.Visible = 'off';
           app.InterpolarCeroButton.Visible = 'off';
        end

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            disp(k);
            [app.Fn, app.inicio_F] = Interpolacion(app,app.Fn, k, app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: DiezmarButton
        function DiezmarButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            disp(k);
            %app.Fn = decimate(app.Fn, k);
            [app.Fn, app.inicio_F] = Diezmacion2(app,app.Fn, k, app.inicio_F);
            %app.inicio_F = min(app.inicio_F):length(app.Fn) - (min(app.inicio_F) + 1); 
            %disp(app.Fn);
            %disp(app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: ReflejoButton_x
        function ReflejoButton_xPushed(app, event)
            [app.xn, app.inicio_x] = Reflejo(app, app.xn, app.inicio_x);
            disp(app.xn);
            disp(app.inicio_x);
            plotXn(app);
            displayReflexValuesXn(app);
        end

        % Button pushed function: ReflejoButton_h
        function ReflejoButton_hPushed(app, event)
            [app.hn, app.inicio_h] = Reflejo(app, app.hn, app.inicio_h);
            disp(app.hn);
            disp(app.inicio_h);
            plotHn(app);
            displayReflexValuesHn(app);
        end

        % Button pushed function: InterhButton_h
        function InterhButton_hPushed(app, event)
            k = app.FactorAmphEditField.Value;
            disp(k);
            [app.hn, app.inicio_h] = Interpolacion(app,app.hn, k, app.inicio_h);
            plotHn(app);
        end

        % Button pushed function: InterpolacinButton_h
        function InterpolacinButton_hPushed(app, event)
            app.InterhButton_h.Visible = 'off';
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            %app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor interpolación(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.DiezhButton_h.Visible = 'off';
            app.InterpolacinLineal_h.Visible='on';
            app.InterpolacinEscalon_h.Visible='on';
            app.InterpolacinCero_h.Visible='on';
            app.TipodeInterpolacinLabel_2.Visible='on';
            app.CancelarButton_h.Visible = 'on';
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            app.InterhButton_h.Visible = 'off';
            app.InterCerohButton_h.Visible = 'off';
            app.InterEschButton_h.Visible = 'off';
        end

        % Button pushed function: DiezmacinButton_h
        function DiezmacinButton_hPushed(app, event)
            app.InterhButton_h.Visible = 'off';
            app.DiezhButton_h.Visible = 'on';
            app.FactorAmphEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor diezmación(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.FactorDesphButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
        end

        % Button pushed function: DiezhButton_h
        function DiezhButton_hPushed(app, event)
            k = app.FactorAmphEditField.Value;
            disp(k);
            
            [app.hn, app.inicio_h] = Diezmacion2(app,app.hn, k, app.inicio_h);
            plotHn(app);
            
        end

        % Button pushed function: InterxButton_x
        function InterxButton_xPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            disp(k);
            [app.xn, app.inicio_x] = Interpolacion(app,app.xn, k, app.inicio_x);
            plotXn(app);
            displayValuesXn(app);
        end

        % Button pushed function: DiezxButton_x
        function DiezxButton_xPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            disp(k);
            
            [app.xn, app.inicio_x] = Diezmacion2(app,app.xn, k, app.inicio_x);
            plotXn(app);
            displayValuesXn(app);
        end

        % Button pushed function: InterpolacinButton_x
        function InterpolacinButton_xPushed(app, event)
            app.InterxButton_x.Visible = 'off';
            app.FactorAmpxEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
           % app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor interpolación(k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'off';
            app.FactorDespxButton.Visible = 'off';
            app.DiezxButton_x.Visible = 'off';
            app.InterpolacinLineal_x.Visible='on';
            app.InterpolacinEscalon_x.Visible='on';
            app.InterpolacinCero_x.Visible='on';
            app.TipodeInterpolacinLabel.Visible='on';
            app.CancelarButton_x.Visible = 'on';
            app.FactorAmpxEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
            app.InterxButton_x.Visible = 'off';
            app.InterCeroxButton_x.Visible = 'off';
            app.InterEscxButton_x.Visible = 'off';
        end

        % Button pushed function: DiezmacinButton_x
        function DiezmacinButton_xPushed(app, event)
            app.InterxButton_x.Visible = 'off';
            app.InterCeroxButton_x.Visible = 'off';
            app.InterEscxButton_x.Visible = 'off';
            app.DiezxButton_x.Visible = 'on';
            app.FactorAmpxEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor diezmación(k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'off';
            app.FactorDespxButton.Visible = 'off';
            app.InterpolacinLineal_x.Visible='off';
            app.InterpolacinEscalon_x.Visible='off';
            app.InterpolacinCero_x.Visible='off';
            app.TipodeInterpolacinLabel.Visible='off';
            
            app.CancelarButton_x.Visible = 'on';
        end

        % Button pushed function: InterpolacinLineal_x
        function InterpolacinLineal_xButtonPushed(app, event)
           app.InterxButton_x.Visible = 'on';
           app.FactorAmpxEditField.Visible = 'on';
           app.InterCeroxButton_x.Visible = 'off';
           app.InterEscxButton_x.Visible = 'off';
           
           app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
           app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor Interp. Lineal(k) =';
           app.FactorAmpxButton.Visible = 'off';
           app.FactorAtnxButton.Visible = 'off';
           app.FactorDespxButton.Visible = 'off';
           app.CancelarButton_x.Visible = 'on';
           app.DiezxButton_x.Visible = 'off';
           app.InterpolacinLineal_x.Visible='off';
           app.InterpolacinEscalon_x.Visible='off';
           app.InterpolacinCero_x.Visible='off';
           app.TipodeInterpolacinLabel.Visible='off';
        end

        % Button pushed function: InterpolacinEscalon_x
        function InterpolacinEscalon_xButtonPushed(app, event)
           app.InterEscxButton_x.Visible = 'on';
           app.FactorAmpxEditField.Visible = 'on';
           app.InterCeroxButton_x.Visible = 'off'; 
           app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
           app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor Interp. Escalón(k) =';
           app.FactorAmpxButton.Visible = 'off';
           app.FactorAtnxButton.Visible = 'off';
           app.FactorDespxButton.Visible = 'off';
           app.CancelarButton_x.Visible = 'on';
           app.DiezxButton_x.Visible = 'off';
           app.InterpolacinLineal_x.Visible='off';
           app.InterpolacinEscalon_x.Visible='off';
           app.InterpolacinCero_x.Visible='off';
           app.TipodeInterpolacinLabel.Visible='off';
        end

        % Button pushed function: InterpolacinCero_x
        function InterpolacinCero_xButtonPushed(app, event)
           app.InterCeroxButton_x.Visible = 'on';
           app.FactorAmpxEditField.Visible = 'on';
            app.InterEscxButton_x.Visible = 'off';
           app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
           app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor Interp. Cero(k) =';
           app.FactorAmpxButton.Visible = 'off';
           app.FactorAtnxButton.Visible = 'off';
           app.FactorDespxButton.Visible = 'off';
           app.CancelarButton_x.Visible = 'on';
           app.DiezxButton_x.Visible = 'off';
           app.InterpolacinLineal_x.Visible='off';
           app.InterpolacinEscalon_x.Visible='off';
           app.InterpolacinCero_x.Visible='off';
           app.TipodeInterpolacinLabel.Visible='off';
        end

        % Button pushed function: InterpolacinLineal_h
        function InterpolacinLineal_hButtonPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.InterhButton_h.Visible = 'on';
            app.InterCeroxButton_h.Visible = 'off';
            app.InterEscxButton_h.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor Interp. Lineal(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.FactorDesphButton.Visible = 'off';
            app.DiezhButton_h.Visible = 'off';
            app.InterpolacinLineal_h.Visible='off';
            app.InterpolacinEscalon_h.Visible='off';
            app.InterpolacinCero_h.Visible='off';
            app.TipodeInterpolacinLabel_2.Visible='off';
            
        end

        % Button pushed function: InterpolacinCero_h
        function InterpolacinCero_hButtonPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.InterCerohButton_h.Visible = 'on';
            app.InterEscxButton_h.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor Interp. Cero(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.FactorDesphButton.Visible = 'off';
            app.DiezhButton_h.Visible = 'off';
            app.InterpolacinLineal_h.Visible='off';
            app.InterpolacinEscalon_h.Visible='off';
            app.InterpolacinCero_h.Visible='off';
            app.TipodeInterpolacinLabel_2.Visible='off';
        end

        % Button pushed function: InterpolacinEscalon_h
        function InterpolacinEscalon_hButtonPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.InterEschButton_h.Visible = 'on';
            app.InterCeroxButton_h.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor Interp. Escalon(k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'off';
            app.CancelarButton_h.Visible = 'on';
            app.FactorDesphButton.Visible = 'off';
            app.DiezhButton_h.Visible = 'off';
            app.InterpolacinLineal_h.Visible='off';
            app.InterpolacinEscalon_h.Visible='off';
            app.InterpolacinCero_h.Visible='off';
            app.TipodeInterpolacinLabel_2.Visible='off';
        end

        % Button pushed function: InterpolacinLineal_F
        function InterpolacinLineal_FButtonPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.InterpolarEscalonButton.Visible = 'off';
            app.InterpolarCeroButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.InterpolarButton.Visible = 'on';
            app.CancelarButton.Visible = 'on';
            app.InterpolacinCero_F.Visible='off';
            app.InterpolacinLineal_F.Visible='off';
            app.InterpolacinEscalon_F.Visible='off';
            app.TipodeInterpolacinLabel_3.Visible='off';
        end

        % Button pushed function: InterpolacinCero_F
        function InterpolacinCero_FButtonPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.InterpolarEscalonButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.InterpolarCeroButton.Visible = 'on';
            app.CancelarButton.Visible = 'on';
            app.InterpolacinCero_F.Visible='off';
            app.InterpolacinLineal_F.Visible='off';
            app.InterpolacinEscalon_F.Visible='off';
            app.TipodeInterpolacinLabel_3.Visible='off';
        end

        % Button pushed function: InterpolacinEscalon_F
        function InterpolacinEscalon_FButtonPushed(app, event)
            app.RestaButton.Visible = 'off';
            app.InterpolarCeroButton.Visible = 'off';
            app.SumaButton.Visible = 'off';
            app.FactorkEditField.Visible = 'on';
            app.FactorkLabel.Visible = 'on';
            app.AmpFButton.Visible = 'off';
            app.AtnFButton.Visible = 'off';
            app.DespFButton.Visible = 'off';
            app.DiezmarButton.Visible = 'off';
            app.InterpolarEscalonButton.Visible = 'on';
            app.CancelarButton.Visible = 'on';
            app.InterpolacinCero_F.Visible='off';
            app.InterpolacinLineal_F.Visible='off';
            app.InterpolacinEscalon_F.Visible='off';
            app.TipodeInterpolacinLabel_3.Visible='off';
        end

        % Button pushed function: InterEscxButton_x
        function InterEscxButton_xPushed(app, event)
            k = app.FactorAmpxEditField.Value;
            disp(k);
            [app.xn, app.inicio_x] = InterpolacionEscalon(app,app.xn, k, app.inicio_x);
            plotXn(app);
            displayValuesXn(app);
        end

        % Button pushed function: InterCeroxButton_x
        function InterCeroxButton_xPushed(app, event)
                  k = app.FactorAmpxEditField.Value;
            disp(k);
            [app.xn, app.inicio_x] = InterpolacionCero(app,app.xn, k, app.inicio_x);
            plotXn(app);
            displayValuesXn(app);
        end

        % Button pushed function: InterCerohButton_h
        function InterCerohButton_hPushed(app, event)
            k = app.FactorAmphEditField.Value;
            disp(k);
            [app.hn, app.inicio_h] = InterpolacionCero(app,app.hn, k, app.inicio_h);
            plotHn(app);
        end

        % Button pushed function: InterEschButton_h
        function InterEschButton_hPushed(app, event)
            k = app.FactorAmphEditField.Value;
            disp(k);
            [app.hn, app.inicio_h] = InterpolacionEscalon(app,app.hn, k, app.inicio_h);
            plotHn(app);
        end

        % Button pushed function: InterpolarCeroButton
        function InterpolarCeroButtonPushed(app, event)
              k = app.FactorkEditField.Value;
            disp(k);
            [app.Fn, app.inicio_F] = InterpolacionCero(app,app.Fn, k, app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end

        % Button pushed function: InterpolarEscalonButton
        function InterpolarEscalonButtonPushed(app, event)
            k = app.FactorkEditField.Value;
            disp(k);
            [app.Fn, app.inicio_F] = InterpolacionEscalon(app,app.Fn, k, app.inicio_F);
            plotFn(app);
            displayValuesFn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'Señal x(n)')
            xlabel(app.UIAxes_x, 'n')
            ylabel(app.UIAxes_x, 'x(n)')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_h = uiaxes(app.UIFigure);
            title(app.UIAxes_h, 'Señal h(n)')
            xlabel(app.UIAxes_h, 'n')
            ylabel(app.UIAxes_h, 'h(n)')
            zlabel(app.UIAxes_h, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal F(n)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.hnEditFieldLabel = uilabel(app.UIFigure);
            app.hnEditFieldLabel.Text = 'h(n) = ';
            app.hnEditField = uieditfield(app.UIFigure, 'text');
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x(n) = ';
            app.xnEditField = uieditfield(app.UIFigure, 'text');
            app.IntroduzcasussealesLabel = uilabel(app.UIFigure);
            app.IntroduzcasussealesLabel.Text = 'Introduzca sus señales';
            app.SumaRestaButton = uibutton(app.UIFigure, 'push');
            app.SumaRestaButton.ButtonPushedFcn = createCallbackFcn(app, @SumaRestaButtonPushed, true);
            app.SumaRestaButton.Visible = 'off';
            app.SumaRestaButton.Text = 'Suma / Resta';
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolucinButtonPushed, true);
            app.ConvolucinButton.Visible = 'off';
            app.ConvolucinButton.Text = 'Convolución';
            app.InterpolacinButton = uibutton(app.UIFigure, 'push');
            app.InterpolacinButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinButtonPushed, true);
            app.InterpolacinButton.Visible = 'off';
            app.InterpolacinButton.Text = 'Interpolación';
            app.DiezmacinButton = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmacinButtonPushed, true);
            app.DiezmacinButton.Visible = 'off';
            app.DiezmacinButton.Text = 'Diezmación ';
            app.AmplificacinButton_F = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_F.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_FPushed, true);
            app.AmplificacinButton_F.Visible = 'off';
            app.AmplificacinButton_F.Text = 'Amplificación';
            app.AtenuacinButton_F = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_F.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_FPushed, true);
            app.AtenuacinButton_F.Visible = 'off';
            app.AtenuacinButton_F.Text = 'Atenuación';
            app.ReflejoButton = uibutton(app.UIFigure, 'push');
            app.ReflejoButton.ButtonPushedFcn = createCallbackFcn(app, @ReflejoButtonPushed, true);
            app.ReflejoButton.Visible = 'off';
            app.ReflejoButton.Text = 'Reflejo';
            app.DesplazamientoButton_F = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_F.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButton_FPushed, true);
            app.DesplazamientoButton_F.Visible = 'off';
            app.DesplazamientoButton_F.Text = 'Desplazamiento';
            app.GraficarxnButton = uibutton(app.UIFigure, 'push');
            app.GraficarxnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarxnButtonPushed, true);
            app.GraficarxnButton.Text = 'Graficar x(n)';
            app.GraficarhnButton = uibutton(app.UIFigure, 'push');
            app.GraficarhnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarhnButtonPushed, true);
            app.GraficarhnButton.Text = 'Graficar h(n)';
            app.PosicininicialEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PosicininicialEditFieldLabel_2.Text = 'Posición inicial =';
            app.inicialEditField_xn = uieditfield(app.UIFigure, 'numeric');
            app.PosicininicialEditField_2Label = uilabel(app.UIFigure);
            app.PosicininicialEditField_2Label.Text = 'Posición inicial =';
            app.inicialEditField_hn = uieditfield(app.UIFigure, 'numeric');
            app.AmplificacinButton_x = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_xPushed, true);
            app.AmplificacinButton_x.Visible = 'off';
            app.AmplificacinButton_x.Text = 'Amplificación';
            app.AtenuacinButton_x = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_xPushed, true);
            app.AtenuacinButton_x.Visible = 'off';
            app.AtenuacinButton_x.Text = 'Atenuación';
            app.AmplificacinButton_h = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_hPushed, true);
            app.AmplificacinButton_h.Visible = 'off';
            app.AmplificacinButton_h.Text = 'Amplificación';
            app.AtenuacinButton_h = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_hPushed, true);
            app.AtenuacinButton_h.Visible = 'off';
            app.AtenuacinButton_h.Text = 'Atenuación';
            app.SealresultadoLabel = uilabel(app.UIFigure);
            app.SealresultadoLabel.Visible = 'off';
            app.SealresultadoLabel.Text = 'Señal resultado';
            app.FactorAmpxButton = uibutton(app.UIFigure, 'push');
            app.FactorAmpxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAmpxButtonPushed, true);
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAmpxButton.Text = 'Amplificar x(n)';
            app.FactorAmphButton = uibutton(app.UIFigure, 'push');
            app.FactorAmphButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAmphButtonPushed, true);
            app.FactorAmphButton.Visible = 'off';
            app.FactorAmphButton.Text = 'Amplificar h(n)';
            app.FactordeamplificacinkEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Text = 'Factor de amplificación (k) =';
            app.FactorAmphEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3 = uilabel(app.UIFigure);
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Text = 'Factor de amplificación (k) =';
            app.FactorAmpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorAmpxEditField.Visible = 'off';
            app.FactorAtnxButton = uibutton(app.UIFigure, 'push');
            app.FactorAtnxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAtnxButtonPushed, true);
            app.FactorAtnxButton.Visible = 'off';
            app.FactorAtnxButton.Text = 'Atenuar x(n)';
            app.FactorAtnhButton = uibutton(app.UIFigure, 'push');
            app.FactorAtnhButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAtnhButtonPushed, true);
            app.FactorAtnhButton.Visible = 'off';
            app.FactorAtnhButton.Text = 'Atenuar h(n)';
            app.FactorkLabel = uilabel(app.UIFigure);
            app.FactorkLabel.Visible = 'off';
            app.FactorkLabel.Text = 'Factor (k) = ';
            app.FactorkEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorkEditField.Visible = 'off';
            app.AmpFButton = uibutton(app.UIFigure, 'push');
            app.AmpFButton.ButtonPushedFcn = createCallbackFcn(app, @AmpFButtonPushed, true);
            app.AmpFButton.Visible = 'off';
            app.AmpFButton.Text = 'Amplificar F(n)';
            app.CancelarButton = uibutton(app.UIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Visible = 'off';
            app.CancelarButton.Text = 'Cancelar';
            app.CancelarButton_x = uibutton(app.UIFigure, 'push');
            app.CancelarButton_x.ButtonPushedFcn = createCallbackFcn(app, @CancelarButton_xPushed, true);
            app.CancelarButton_x.Visible = 'off';
            app.CancelarButton_x.Text = 'Cancelar';
            app.CancelarButton_h = uibutton(app.UIFigure, 'push');
            app.CancelarButton_h.ButtonPushedFcn = createCallbackFcn(app, @CancelarButton_hPushed, true);
            app.CancelarButton_h.Visible = 'off';
            app.CancelarButton_h.Text = 'Cancelar';
            app.AtnFButton = uibutton(app.UIFigure, 'push');
            app.AtnFButton.ButtonPushedFcn = createCallbackFcn(app, @AtnFButtonPushed, true);
            app.AtnFButton.Visible = 'off';
            app.AtnFButton.Text = 'Atenuar F(n)';
            app.SumaButton = uibutton(app.UIFigure, 'push');
            app.SumaButton.ButtonPushedFcn = createCallbackFcn(app, @SumaButtonPushed, true);
            app.SumaButton.Visible = 'off';
            app.SumaButton.Text = 'Suma';
            app.RestaButton = uibutton(app.UIFigure, 'push');
            app.RestaButton.ButtonPushedFcn = createCallbackFcn(app, @RestaButtonPushed, true);
            app.RestaButton.Visible = 'off';
            app.RestaButton.Text = 'Resta';
            app.Practica2OperacionesbasicasLabel = uilabel(app.UIFigure);
            app.Practica2OperacionesbasicasLabel.Text = 'Practica 2: Operaciones basicas';
            app.InstruccionesLabel_1 = uilabel(app.UIFigure);
            app.InstruccionesLabel_1.Text = '1) Escriba las muestras de su señal x(n), escriba la posición de la muestra que sera n = 0, grafiquela y aplique una amplificación o atenuación si lo desea. Las posiciones iniciales empiezan contando al primer elemento como 0.';
            app.InstruccionesLabel_2 = uilabel(app.UIFigure);
            app.InstruccionesLabel_2.Text = '2) Escriba las muestras de su señal h(n), escriba la posición de la muestra que sera n = 0, grafiquela y aplique una amplificación o atenuación si lo desea. Las posiciones iniciales empiezan contando al primer elemento como 0.';
            app.InstruccionesLabel_3 = uilabel(app.UIFigure);
            app.InstruccionesLabel_3.Text = '3) A partir de eso aparecera una tercera grafica, donde podra hacer las operaciones de suma, resta y convolución.';
            app.InstruccionesLabel_4 = uilabel(app.UIFigure);
            app.InstruccionesLabel_4.Text = '4) A esa tercera señal resultante de dichas operaciones anteriores, llamada F(n), se le podran hacer las operaciones de reflejo, desplazamiento, amplificación, etc.';
            app.DesplazamientoButton_x = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_x.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButton_xPushed, true);
            app.DesplazamientoButton_x.Visible = 'off';
            app.DesplazamientoButton_x.Text = 'Desplazamiento';
            app.FactorDespxButton = uibutton(app.UIFigure, 'push');
            app.FactorDespxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorDespxButtonPushed, true);
            app.FactorDespxButton.Visible = 'off';
            app.FactorDespxButton.Text = 'Desplazar x(n)';
            app.DespFButton = uibutton(app.UIFigure, 'push');
            app.DespFButton.ButtonPushedFcn = createCallbackFcn(app, @DespFButtonPushed, true);
            app.DespFButton.Visible = 'off';
            app.DespFButton.Text = 'Desplazar F(n)';
            app.DesplazamientoButton_h = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_h.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButton_hPushed, true);
            app.DesplazamientoButton_h.Visible = 'off';
            app.DesplazamientoButton_h.Text = 'Desplazamiento';
            app.FactorDesphButton = uibutton(app.UIFigure, 'push');
            app.FactorDesphButton.ButtonPushedFcn = createCallbackFcn(app, @FactorDesphButtonPushed, true);
            app.FactorDesphButton.Visible = 'off';
            app.FactorDesphButton.Text = 'Desplazar h(n)';
            app.InterpolarButton = uibutton(app.UIFigure, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Visible = 'off';
            app.InterpolarButton.Text = 'Interpolar';
            app.DiezmarButton = uibutton(app.UIFigure, 'push');
            app.DiezmarButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmarButtonPushed, true);
            app.DiezmarButton.Visible = 'off';
            app.DiezmarButton.Text = 'Diezmar';
            app.ReflejoButton_h = uibutton(app.UIFigure, 'push');
            app.ReflejoButton_h.ButtonPushedFcn = createCallbackFcn(app, @ReflejoButton_hPushed, true);
            app.ReflejoButton_h.Visible = 'off';
            app.ReflejoButton_h.Text = 'Reflejo';
            app.ReflejoButton_x = uibutton(app.UIFigure, 'push');
            app.ReflejoButton_x.ButtonPushedFcn = createCallbackFcn(app, @ReflejoButton_xPushed, true);
            app.ReflejoButton_x.Visible = 'off';
            app.ReflejoButton_x.Text = 'Reflejo';
            app.InterpolacinButton_h = uibutton(app.UIFigure, 'push');
            app.InterpolacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinButton_hPushed, true);
            app.InterpolacinButton_h.Visible = 'off';
            app.InterpolacinButton_h.Text = 'Interpolación';
            app.DiezmacinButton_h = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @DiezmacinButton_hPushed, true);
            app.DiezmacinButton_h.Visible = 'off';
            app.DiezmacinButton_h.Text = 'Diezmación ';
            app.InterpolacinButton_x = uibutton(app.UIFigure, 'push');
            app.InterpolacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinButton_xPushed, true);
            app.InterpolacinButton_x.Visible = 'off';
            app.InterpolacinButton_x.Text = 'Interpolación';
            app.DiezmacinButton_x = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @DiezmacinButton_xPushed, true);
            app.DiezmacinButton_x.Visible = 'off';
            app.DiezmacinButton_x.Text = 'Diezmación ';
            app.InterhButton_h = uibutton(app.UIFigure, 'push');
            app.InterhButton_h.ButtonPushedFcn = createCallbackFcn(app, @InterhButton_hPushed, true);
            app.InterhButton_h.Visible = 'off';
            app.InterhButton_h.Text = 'Interpolar h(n)';
            app.DiezhButton_h = uibutton(app.UIFigure, 'push');
            app.DiezhButton_h.ButtonPushedFcn = createCallbackFcn(app, @DiezhButton_hPushed, true);
            app.DiezhButton_h.Visible = 'off';
            app.DiezhButton_h.Text = 'Diezmar h(n)';
            app.DiezxButton_x = uibutton(app.UIFigure, 'push');
            app.DiezxButton_x.ButtonPushedFcn = createCallbackFcn(app, @DiezxButton_xPushed, true);
            app.DiezxButton_x.Visible = 'off';
            app.DiezxButton_x.Text = 'Diezmar x(n)';
            app.InterxButton_x = uibutton(app.UIFigure, 'push');
            app.InterxButton_x.ButtonPushedFcn = createCallbackFcn(app, @InterxButton_xPushed, true);
            app.InterxButton_x.Visible = 'off';
            app.InterxButton_x.Text = 'Interpolar Lineal x(n)';
            app.OrigenLabel_2 = uilabel(app.UIFigure);
            app.OrigenLabel_2.Visible = 'off';
            app.OrigenLabel_2.Text = 'Origen :';
            app.OrigenLabel = uilabel(app.UIFigure);
            app.OrigenLabel.Visible = 'off';
            app.OrigenLabel.Text = 'Origen : ';
            app.OrigenLabel_3 = uilabel(app.UIFigure);
            app.OrigenLabel_3.Visible = 'off';
            app.OrigenLabel_3.Text = 'Origen :';
            app.SealxnLabel = uilabel(app.UIFigure);
            app.SealxnLabel.Visible = 'off';
            app.SealxnLabel.Text = 'Señal x(n) = ';
            app.disxnEditField = uieditfield(app.UIFigure, 'text');
            app.disxnEditField.Visible = 'off';
            app.SealhnLabel = uilabel(app.UIFigure);
            app.SealhnLabel.Visible = 'off';
            app.SealhnLabel.Text = 'Señal h(n) = ';
            app.dishnEditField = uieditfield(app.UIFigure, 'text');
            app.dishnEditField.Visible = 'off';
            app.SealFnLabel = uilabel(app.UIFigure);
            app.SealFnLabel.Visible = 'off';
            app.SealFnLabel.Text = 'Señal F(n) = ';
            app.disFnEditField = uieditfield(app.UIFigure, 'text');
            app.disFnEditField.Visible = 'off';
            app.OrigenxnLabel_value = uilabel(app.UIFigure);
            app.OrigenxnLabel_value.Visible = 'off';
            app.OrigenxnLabel_value.Text = 'Origen x(n)';
            app.OrigenhnLabel_value = uilabel(app.UIFigure);
            app.OrigenhnLabel_value.Visible = 'off';
            app.OrigenhnLabel_value.Text = 'Origen h(n)';
            app.OrigenFnLabel_value = uilabel(app.UIFigure);
            app.OrigenFnLabel_value.Visible = 'off';
            app.OrigenFnLabel_value.Text = 'Origen F(n)';
            app.InterpolacinLineal_x = uibutton(app.UIFigure, 'push');
            app.InterpolacinLineal_x.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinLineal_xButtonPushed, true);
            app.InterpolacinLineal_x.Visible = 'off';
            app.InterpolacinLineal_x.Text = 'I. Lineal';
            app.InterpolacinEscalon_x = uibutton(app.UIFigure, 'push');
            app.InterpolacinEscalon_x.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinEscalon_xButtonPushed, true);
            app.InterpolacinEscalon_x.Visible = 'off';
            app.InterpolacinEscalon_x.Text = 'I. Escalón';
            app.InterpolacinCero_x = uibutton(app.UIFigure, 'push');
            app.InterpolacinCero_x.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinCero_xButtonPushed, true);
            app.InterpolacinCero_x.Visible = 'off';
            app.InterpolacinCero_x.Text = 'I. Cero';
            app.TipodeInterpolacinLabel = uilabel(app.UIFigure);
            app.TipodeInterpolacinLabel.Visible = 'off';
            app.TipodeInterpolacinLabel.Text = 'Tipo de Interpolación: ';
            app.InterpolacinLineal_h = uibutton(app.UIFigure, 'push');
            app.InterpolacinLineal_h.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinLineal_hButtonPushed, true);
            app.InterpolacinLineal_h.Visible = 'off';
            app.InterpolacinLineal_h.Text = 'I. Lineal';
            app.InterpolacinEscalon_h = uibutton(app.UIFigure, 'push');
            app.InterpolacinEscalon_h.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinEscalon_hButtonPushed, true);
            app.InterpolacinEscalon_h.Visible = 'off';
            app.InterpolacinEscalon_h.Text = 'I. Escalón';
            app.InterpolacinCero_h = uibutton(app.UIFigure, 'push');
            app.InterpolacinCero_h.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinCero_hButtonPushed, true);
            app.InterpolacinCero_h.Visible = 'off';
            app.InterpolacinCero_h.Text = 'I. Cero';
            app.TipodeInterpolacinLabel_2 = uilabel(app.UIFigure);
            app.TipodeInterpolacinLabel_2.Visible = 'off';
            app.TipodeInterpolacinLabel_2.Text = 'Tipo de Interpolación: ';
            app.InterpolacinLineal_F = uibutton(app.UIFigure, 'push');
            app.InterpolacinLineal_F.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinLineal_FButtonPushed, true);
            app.InterpolacinLineal_F.Visible = 'off';
            app.InterpolacinLineal_F.Text = 'I. Lineal';
            app.InterpolacinEscalon_F = uibutton(app.UIFigure, 'push');
            app.InterpolacinEscalon_F.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinEscalon_FButtonPushed, true);
            app.InterpolacinEscalon_F.Visible = 'off';
            app.InterpolacinEscalon_F.Text = 'I. Escalón';
            app.TipodeInterpolacinLabel_3 = uilabel(app.UIFigure);
            app.TipodeInterpolacinLabel_3.Visible = 'off';
            app.TipodeInterpolacinLabel_3.Text = 'Tipo de Interpolación: ';
            app.InterpolacinCero_F = uibutton(app.UIFigure, 'push');
            app.InterpolacinCero_F.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinCero_FButtonPushed, true);
            app.InterpolacinCero_F.Visible = 'off';
            app.InterpolacinCero_F.Text = 'I. Cero';
            app.InterEscxButton_x = uibutton(app.UIFigure, 'push');
            app.InterEscxButton_x.ButtonPushedFcn = createCallbackFcn(app, @InterEscxButton_xPushed, true);
            app.InterEscxButton_x.Visible = 'off';
            app.InterEscxButton_x.Text = 'Interpolar Escalón x(n)';
            app.InterCeroxButton_x = uibutton(app.UIFigure, 'push');
            app.InterCeroxButton_x.ButtonPushedFcn = createCallbackFcn(app, @InterCeroxButton_xPushed, true);
            app.InterCeroxButton_x.Visible = 'off';
            app.InterCeroxButton_x.Text = 'Interpolar  Cero x(n)';
            app.InterEschButton_h = uibutton(app.UIFigure, 'push');
            app.InterEschButton_h.ButtonPushedFcn = createCallbackFcn(app, @InterEschButton_hPushed, true);
            app.InterEschButton_h.Visible = 'off';
            app.InterEschButton_h.Text = 'Interpolar Escalón h(n)';
            app.InterCerohButton_h = uibutton(app.UIFigure, 'push');
            app.InterCerohButton_h.ButtonPushedFcn = createCallbackFcn(app, @InterCerohButton_hPushed, true);
            app.InterCerohButton_h.Visible = 'off';
            app.InterCerohButton_h.Text = 'Interpolar  Cero h(n)';
            app.InterpolarEscalonButton = uibutton(app.UIFigure, 'push');
            app.InterpolarEscalonButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarEscalonButtonPushed, true);
            app.InterpolarEscalonButton.Visible = 'off';
            app.InterpolarEscalonButton.Text = 'Interpolar Escalon';
            app.InterpolarCeroButton = uibutton(app.UIFigure, 'push');
            app.InterpolarCeroButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarCeroButtonPushed, true);
            app.InterpolarCeroButton.Visible = 'off';
            app.InterpolarCeroButton.Text = 'Interpolar Cero';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperacionesBasicas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end